classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ResimYkleTab                  matlab.ui.container.Tab
        ResimYkleButton               matlab.ui.control.Button
        ResmiKaydetButton             matlab.ui.control.Button
        ilk1                          matlab.ui.control.UIAxes
        NoktaBazllemlerTab            matlab.ui.container.Tab
        ilk2                          matlab.ui.control.UIAxes
        son1                          matlab.ui.control.UIAxes
        ResmiKaydetButton_2           matlab.ui.control.Button
        SeeneklerDropDownLabel        matlab.ui.control.Label
        SeeneklerDropDown             matlab.ui.control.DropDown
        isle1                         matlab.ui.control.Button
        DeerSliderLabel               matlab.ui.control.Label
        DeerSlider                    matlab.ui.control.Slider
        FiltreilemleriTab             matlab.ui.container.Tab
        son2                          matlab.ui.control.UIAxes
        ilk3                          matlab.ui.control.UIAxes
        ResmiKaydetButton_5           matlab.ui.control.Button
        SeeneklerDropDown_4Label      matlab.ui.control.Label
        SeeneklerDropDown_4           matlab.ui.control.DropDown
        isle2                         matlab.ui.control.Button
        MorfolojikilemlerTab          matlab.ui.container.Tab
        ilk4                          matlab.ui.control.UIAxes
        son3                          matlab.ui.control.UIAxes
        ResmiKaydetButton_6           matlab.ui.control.Button
        SeeneklerDropDown_5Label      matlab.ui.control.Label
        SeeneklerDropDown_5           matlab.ui.control.DropDown
        isle3                         matlab.ui.control.Button
        GrntSktrmailemleriTab         matlab.ui.container.Tab
        GrntSktrmabiimiseinizListBoxLabel  matlab.ui.control.Label
        GrntSktrmabiimiseinizListBox  matlab.ui.control.ListBox
        SktrveKaydetButton            matlab.ui.control.Button
    end


    properties (Access = public)
        image="" % Description
        value1=''
        value2=''
        value3=''
        value4=''
        slidervalue=0
        outputimage=0
    end


    methods (Access = private)

        % Button pushed function: ResimYkleButton
        function ResimYkleButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.jpg';'*.gif';'*.png';'*.ppm';'*.pgm'}, 'Görüntü Dosyasını Seç:','Multiselect','On');
            a= imread(filename);
            imshow(a, 'parent', app.ilk1);
            imshow(a, 'parent', app.ilk2);
            imshow(a, 'parent', app.ilk3);
            imshow(a, 'parent', app.ilk4);
            app.image=a
            app.outputimage=a
            
        end

        % Button pushed function: ResmiKaydetButton
        function ResmiKaydetButtonPushed(app, event)
            imwrite(app.image,'Savedimage.jpeg','JPEG');
        end

        % Value changed function: SeeneklerDropDown
        function SeeneklerDropDownValueChanged(app, event)
            value = app.SeeneklerDropDown.Value;
            app.value1=value
        end

        % Button pushed function: isle1
        function isle1ButtonPushed(app, event)
            img=app.image;
            if strcmp(app.value1,"Eşikleme")
            img=rgb2gray(img); % resimi griye çevirme
            level = graythresh(img); %  otsu’s threshold yöntemi ile sınır değer belirleme
            img =im2bw(img,level);
            imshow(img, 'parent',app.son1)
            app.outputimage=img
            elseif strcmp(app.value1,"Log dönüşümü")
                f=app.image;
                g=rgb2gray(f);
                c=app.slidervalue;
                [M,N]=size(g);
                        for x = 1:M
                            for y = 1:N
                                m=double(g(x,y));
                                z(x,y)=c.*log10(1+m); %#ok<AGROW>
                            end
                        end
                imshow(z, 'parent',app.son1);
                app.outputimage=z
            elseif strcmp(app.value1,"Gri Seviye Dönüşüm")
                a=app.image;
                e=graythresh(a);
                BW = im2bw(a,e);
                imshow(BW, 'parent',app.son1);
                app.outputimage=BW
            elseif strcmp(app.value1,"Kontrast Germe")
                im=app.image;
                im_strach=(im-min(im(:)))*(255/(max(im(:))-min(im(:))));
                imshow(im, 'parent',app.son1);
                app.outputimage=im
            elseif strcmp(app.value1,"Histogram Eşitleme")
                a=app.image;
                r = size(a,1);
                c = size(a,2);
                ah=uint8(zeros(r,c));
                n = r*c;
                f = zeros(256,1);
                pdf = zeros(256,1);
                cdf = zeros(256,1);
                cum = zeros(256,1);
                out = zeros(256,1);
                I2=im2double(a);
                m=mean2(I2);
                contrast1=1./(1+(m./(I2+eps)).^4);
                for i=1:r
                for j=1:c
                value=a(i,j);
                f(value+1)=f(value+1)+1;
                pdf(value+1)=f(value+1)/n;
                end
                end
                sum=0; L=255;
                for i=1:size(pdf)
                sum = sum +f(i);
                cum(i) = sum;
                cdf(i) = cum(i)/n;
                out(i) = round(cdf(i)*L);
                end
                for i=1:r
                for j=1:c
                ah(i,j)=out(a(i,j)+1);
                end
                end
              
                imshow(ah, 'parent',app.son1);
                app.outputimage=ah
            end
        end

        % Value changed function: DeerSlider
        function DeerSliderValueChanged(app, event)
            value = app.DeerSlider.Value;
            app.slidervalue=value;
        end

        % Value changed function: SeeneklerDropDown_4
        function SeeneklerDropDown_4ValueChanged(app, event)
            value = app.SeeneklerDropDown_4.Value;
            app.value2=value
        end

        % Button pushed function: isle2
        function isle2ButtonPushed(app, event)
            img=app.image;
            if strcmp(app.value2,"Mean")
                    I= img;
                    I = rgb2gray(I);
                    [R,C] = size(I);
                    SI = uint8(zeros(R,C));
                    for r=1:R
                    for c=1:C
                    ListOfValues = 1:9;
                    counter = 0;
                    for i=-1:1
                    for j=-1:1
                    counter = counter + 1;
                    if(((r+i)>0) && ((c+j)>0) && ((r+i)<=R) && ((c+j)<=C))
                    ListOfValues(counter) = I(r+i,c+j);
                    end
                    end
                    end
                    SI(r,c) = median(ListOfValues);
                    ListOfValues = [];
                    end
                    end
 
                    imshow(SI, 'parent',app.son2);
                    app.outputimage=SI;
            elseif strcmp(app.value2,"Median")
                    
                    J = medfilt3(img)
                    imshow(J, 'parent',app.son2);
                    app.outputimage=J;
            elseif strcmp(app.value2,"Gauss")
                
                    B = imgaussfilt3(img)
                    imshow(B, 'parent',app.son2);
                    app.outputimage=B;
            elseif strcmp(app.value2,"Sobel")
                    img=rgb2gray(img);
                    A = edge(img,'Sobel');
                    imshow(A, 'parent',app.son2);
                    app.outputimage=A;
            elseif strcmp(app.value2,"Prewitt")
                    img=rgb2gray(img);
                    A = edge(img,'Prewitt');
                    imshow(A, 'parent',app.son2);
                    app.outputimage=A;
            elseif strcmp(app.value2,"Laplace")
                    img=rgb2gray(img);
                    A = edge(img,'log');
                    imshow(A, 'parent',app.son2);
                    app.outputimage=A;
                
            elseif strcmp(app.value2,"Roberts")
                    img=rgb2gray(img);
                    A = edge(img,'Roberts');
                    imshow(A, 'parent',app.son2);
                    app.outputimage=A
                
            end
        end

        % Value changed function: SeeneklerDropDown_5
        function SeeneklerDropDown_5ValueChanged(app, event)
            value = app.SeeneklerDropDown_5.Value;
            app.value3=value;
        end

        % Button pushed function: isle3
        function isle3ButtonPushed(app, event)
            img=app.image;
            if strcmp(app.value3,"Aşınma")
                bw = img;
                lvl=graythresh(bw);
                bw=im2bw(bw,lvl);
                se = strel('line',11,70);
                se1 = strel('square',8);
                bw3 = imerode(bw,se1);
                imshow(bw3, 'parent',app.son3);
                app.outputimage=bw3;
            elseif strcmp(app.value3,"Genişleme")
                bw = img;
                lvl=graythresh(bw);
                bw=im2bw(bw,lvl);
                se = strel('line',11,70);
                se1 = strel('square',8);
                bw3 = imdilate(bw,se)
                imshow(bw3, 'parent',app.son3);
                app.outputimage=bw3;
            elseif strcmp(app.value3,"Açma")
                f = img;
                t = strel('square',50);
                f = imopen(f,t);
                imshow(f, 'parent',app.son3);
                app.outputimage=f;
            elseif strcmp(app.value3,"Kapatma")
                f = img;
                t = strel('square',50);
                f = imclose(f,t);
                imshow(f, 'parent',app.son3);
                app.outputimage=f;
            end
        end

        % Button pushed function: ResmiKaydetButton_2
        function ResmiKaydetButton_2Pushed(app, event)
            imwrite(app.outputimage,'Savedimage.jpeg','JPEG');
        end

        % Size changed function: FiltreilemleriTab
        function FiltreilemleriTabSizeChanged(app, event)
            position = app.FiltreilemleriTab.Position;
            
        end

        % Button pushed function: ResmiKaydetButton_5
        function ResmiKaydetButton_5Pushed(app, event)
            imwrite(app.outputimage,'Savedimage.jpeg','JPEG');
        end

        % Button pushed function: ResmiKaydetButton_6
        function ResmiKaydetButton_6Pushed(app, event)
            imwrite(app.outputimage,'Savedimage.jpeg','JPEG');
        end

        % Value changed function: GrntSktrmabiimiseinizListBox
        function GrntSktrmabiimiseinizListBoxValueChanged(app, event)
            value = app.GrntSktrmabiimiseinizListBox.Value;
            app.value4=value
        end

        % Button pushed function: SktrveKaydetButton
        function SktrveKaydetButtonPushed(app, event)
            img=app.image;
            s = strcat('Savedimage.',app.value4)
            imwrite(app.outputimage,s,app.value4);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResimYkleTab = uitab(app.TabGroup);
            app.ResimYkleTab.Title = 'Resim Yükle';
            app.ResimYkleButton = uibutton(app.ResimYkleTab, 'push');
            app.ResimYkleButton.ButtonPushedFcn = createCallbackFcn(app, @ResimYkleButtonPushed, true);
            app.ResimYkleButton.Text = 'Resim Yükle';
            app.ResmiKaydetButton = uibutton(app.ResimYkleTab, 'push');
            app.ResmiKaydetButton.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButtonPushed, true);
            app.ResmiKaydetButton.Text = 'Resmi Kaydet';
            app.ilk1 = uiaxes(app.ResimYkleTab);
            title(app.ilk1, 'Resim')
            app.NoktaBazllemlerTab = uitab(app.TabGroup);
            app.NoktaBazllemlerTab.Title = 'Nokta Bazlı İşlemler';
            app.ilk2 = uiaxes(app.NoktaBazllemlerTab);
            title(app.ilk2, 'İlk hali')
            app.son1 = uiaxes(app.NoktaBazllemlerTab);
            title(app.son1, 'son hali')
            app.ResmiKaydetButton_2 = uibutton(app.NoktaBazllemlerTab, 'push');
            app.ResmiKaydetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButton_2Pushed, true);
            app.ResmiKaydetButton_2.Text = 'Resmi Kaydet';
            app.SeeneklerDropDownLabel = uilabel(app.NoktaBazllemlerTab);
            app.SeeneklerDropDownLabel.Text = 'Seçenekler';
            app.SeeneklerDropDown = uidropdown(app.NoktaBazllemlerTab);
            app.SeeneklerDropDown.Items = {'Lütfen Seçim Yapınız', 'Eşikleme', 'Log dönüşümü', 'Gri Seviye Dönüşüm', 'Kontrast Germe', 'Histogram Eşitleme'};
            app.SeeneklerDropDown.ValueChangedFcn = createCallbackFcn(app, @SeeneklerDropDownValueChanged, true);
            app.SeeneklerDropDown.Value = 'Lütfen Seçim Yapınız';
            app.isle1 = uibutton(app.NoktaBazllemlerTab, 'push');
            app.isle1.ButtonPushedFcn = createCallbackFcn(app, @isle1ButtonPushed, true);
            app.isle1.Text = 'İşle';
            app.DeerSliderLabel = uilabel(app.NoktaBazllemlerTab);
            app.DeerSliderLabel.Text = 'Değer';
            app.DeerSlider = uislider(app.NoktaBazllemlerTab);
            app.DeerSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.DeerSlider.ValueChangedFcn = createCallbackFcn(app, @DeerSliderValueChanged, true);
            app.FiltreilemleriTab = uitab(app.TabGroup);
            app.FiltreilemleriTab.SizeChangedFcn = createCallbackFcn(app, @FiltreilemleriTabSizeChanged, true);
            app.FiltreilemleriTab.Title = 'Filtre işlemleri';
            app.son2 = uiaxes(app.FiltreilemleriTab);
            title(app.son2, 'son hali')
            app.ilk3 = uiaxes(app.FiltreilemleriTab);
            title(app.ilk3, 'İlk hali')
            app.ResmiKaydetButton_5 = uibutton(app.FiltreilemleriTab, 'push');
            app.ResmiKaydetButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButton_5Pushed, true);
            app.ResmiKaydetButton_5.Text = 'Resmi Kaydet';
            app.SeeneklerDropDown_4Label = uilabel(app.FiltreilemleriTab);
            app.SeeneklerDropDown_4Label.Text = 'Seçenekler';
            app.SeeneklerDropDown_4 = uidropdown(app.FiltreilemleriTab);
            app.SeeneklerDropDown_4.Items = {'Lütfen Bir seçim yapınız', 'Mean', 'Median', 'Gauss', 'Sobel', 'Prewitt', 'Laplace', 'Roberts'};
            app.SeeneklerDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SeeneklerDropDown_4ValueChanged, true);
            app.SeeneklerDropDown_4.Value = 'Mean';
            app.isle2 = uibutton(app.FiltreilemleriTab, 'push');
            app.isle2.ButtonPushedFcn = createCallbackFcn(app, @isle2ButtonPushed, true);
            app.isle2.Text = 'Filtrele';
            app.MorfolojikilemlerTab = uitab(app.TabGroup);
            app.MorfolojikilemlerTab.Title = 'Morfolojik işlemler';
            app.ilk4 = uiaxes(app.MorfolojikilemlerTab);
            title(app.ilk4, 'İlk hali')
            app.son3 = uiaxes(app.MorfolojikilemlerTab);
            title(app.son3, 'son hali')
            app.ResmiKaydetButton_6 = uibutton(app.MorfolojikilemlerTab, 'push');
            app.ResmiKaydetButton_6.ButtonPushedFcn = createCallbackFcn(app, @ResmiKaydetButton_6Pushed, true);
            app.ResmiKaydetButton_6.Text = 'Resmi Kaydet';
            app.SeeneklerDropDown_5Label = uilabel(app.MorfolojikilemlerTab);
            app.SeeneklerDropDown_5Label.Text = 'Seçenekler';
            app.SeeneklerDropDown_5 = uidropdown(app.MorfolojikilemlerTab);
            app.SeeneklerDropDown_5.Items = {'Lütfen Bir seçim yapınız', 'Aşınma', 'Genişleme', 'Açma', 'Kapatma'};
            app.SeeneklerDropDown_5.ValueChangedFcn = createCallbackFcn(app, @SeeneklerDropDown_5ValueChanged, true);
            app.SeeneklerDropDown_5.Value = 'Lütfen Bir seçim yapınız';
            app.isle3 = uibutton(app.MorfolojikilemlerTab, 'push');
            app.isle3.ButtonPushedFcn = createCallbackFcn(app, @isle3ButtonPushed, true);
            app.isle3.Text = 'İşle';
            app.GrntSktrmailemleriTab = uitab(app.TabGroup);
            app.GrntSktrmailemleriTab.Title = 'Görüntü Sıkıştırma işlemleri';
            app.GrntSktrmabiimiseinizListBoxLabel = uilabel(app.GrntSktrmailemleriTab);
            app.GrntSktrmabiimiseinizListBoxLabel.Text = 'Görüntü Sıkıştırma biçimi seçiniz';
            app.GrntSktrmabiimiseinizListBox = uilistbox(app.GrntSktrmailemleriTab);
            app.GrntSktrmabiimiseinizListBox.Items = {'JPEG', 'JPG', 'BMP', 'PNG'};
            app.GrntSktrmabiimiseinizListBox.ValueChangedFcn = createCallbackFcn(app, @GrntSktrmabiimiseinizListBoxValueChanged, true);
            app.GrntSktrmabiimiseinizListBox.Value = 'JPEG';
            app.SktrveKaydetButton = uibutton(app.GrntSktrmailemleriTab, 'push');
            app.SktrveKaydetButton.ButtonPushedFcn = createCallbackFcn(app, @SktrveKaydetButtonPushed, true);
            app.SktrveKaydetButton.Text = 'Sıkıştır ve Kaydet';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end